classdef ICP_Base < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        BaseMenu                        matlab.ui.container.Menu
        OpenBaseMenu                    matlab.ui.container.Menu
        SaveBaseMenu                    matlab.ui.container.Menu
        SaveBaseasMenu                  matlab.ui.container.Menu
        ProjectMenu                     matlab.ui.container.Menu
        NewProjectMenu                  matlab.ui.container.Menu
        OpenProjectMenu                 matlab.ui.container.Menu
        SaveProjectMenu                 matlab.ui.container.Menu
        SaveProjectasMenu               matlab.ui.container.Menu
        InfosMenu                       matlab.ui.container.Menu
        GridLayout                      matlab.ui.container.GridLayout
        SendSessiontoPlotModuleButton   matlab.ui.control.Button
        SendBasetoPlotModuleButton      matlab.ui.control.Button
        SendProjecttoPlotModuleButton   matlab.ui.control.Button
        DuplicateProjectButton          matlab.ui.control.Button
        DuplicateSessionButton          matlab.ui.control.Button
        SelectButton                    matlab.ui.control.Button
        WorkingDirectoryEditField       matlab.ui.control.EditField
        WorkingDirectoryEditFieldLabel  matlab.ui.control.Label
        ResultsButton                   matlab.ui.control.Button
        QuantificationButton            matlab.ui.control.Button
        DriftButton                     matlab.ui.control.Button
        ClassificationButton            matlab.ui.control.Button
        DeleteProjectButton             matlab.ui.control.Button
        AddProjectButton                matlab.ui.control.Button
        ProjectTable                    matlab.ui.control.Table
        IntervalButton                  matlab.ui.control.Button
        DeleteSessionButton             matlab.ui.control.Button
        SessionTable                    matlab.ui.control.Table
        AddSessionButton                matlab.ui.control.Button
        ImportButton                    matlab.ui.control.Button
    end


    properties (Access = public)
        base
        installpath % Description

    end

    properties (Access = private)

        LastSelectedProjectRow
        CurrentReleaseTag = 'GitHubTag' % Description

    end


    methods (Access = private)

        function update_base_GUI(app, project_id)

            update_project_table(app)
            update_session_table(app, project_id)

        end

        function update_project_table(app)

            % preallocate the columns
            project_name=cell(1,numel(app.base));
            %project_comment=cell(1,numel(app.base));

            % get the session names and other variables
            for i = 1:numel(app.base)
                % since the project name is now initialzed at
                % AddSessionButtonDown it will always have 'New Project'
                if isfield(app.base(i), 'project_name') && ~isempty(app.base(i).project_name)

                    project_name{i} = app.base(i).project_name;

                else
                    project_name{i}='New Project';
                end

            end

            % generate a table with the variables
            T=table((1:numel(project_name))', project_name');
            T.Properties.VariableNames={'#', 'Project Name'};

            %push this table to the displayed SessionTable and give them
            %the same column names
            app.ProjectTable.Data = T;
            app.ProjectTable.ColumnName = T.Properties.VariableNames;

        end

        % Att: this function should just be GUI stuff and it should do
        % nothing or as little as possible with the actual data structure
        function update_session_table(app, project_id)

            % preallocate the columns
            session_name=cell(1,numel(app.base(project_id).project));
            session_comment=cell(1,numel(app.base(project_id).project));
            session_date = cell(1,numel(app.base(project_id).project));
            session_analyst = cell(1,numel(app.base(project_id).project));

            % get the session names and other variables
            for i = 1:numel(app.base(project_id).project)

                session_name{i} = app.base(project_id).project(i).session.session_name;

                if isfield(app.base(project_id).project(i).session.config, 'session_comment') && ~isempty(app.base(project_id).project(i).session.config.session_comment)
                    session_comment{i} = app.base(project_id).project(i).session.config.session_comment;
                else
                    session_comment{i}='';
                end

                if isfield(app.base(project_id).project(i).session.config, 'date') && ~isempty(app.base(project_id).project(i).session.config.date)
                    session_date{i} = app.base(project_id).project(i).session.config.date;
                else
                    session_date{i}='';
                end

                if isfield(app.base(project_id).project(i).session.config, 'analyst') && ~isempty(app.base(project_id).project(i).session.config.analyst)
                    session_analyst{i} = app.base(project_id).project(i).session.config.analyst;
                else
                    session_analyst{i}='';
                end

            end

            % generate a table with the variables
            T=table((1:numel(session_name))', session_name', session_date', session_comment', session_analyst');
            T.Properties.VariableNames={'#', 'Session Name', 'Date YYYYMMDD', 'Comment', 'Analyst'};

            %push this table to the displayed SessionTable and give them
            %the same column names
            app.SessionTable.Data = T;
            app.SessionTable.ColumnName = T.Properties.VariableNames;

        end

        function update_progress_buttons(app, project_id, session_id)

            status = app.base(project_id).project(session_id).session.config.module_status;

            green = [204/255, 1, 204/255];
            orange = [1, 229/255, 204/255];
            red = [1, 204/255, 204/255];


            app.ImportButton.Visible = "on";
            app.IntervalButton.Visible = "on";
            app.ClassificationButton.Visible = "on";
            app.DriftButton.Visible = "on";
            app.QuantificationButton.Visible = "on";
            app.ResultsButton.Visible = "on";

            if isfield(status, 'import') && status.import == true
                app.ImportButton.Enable = "on";
                app.ImportButton.BackgroundColor = green;
            else
                app.ImportButton.Enable = "off";
                app.ImportButton.BackgroundColor = red;
            end

            if isfield(status, 'interval') && status.interval == true
                app.IntervalButton.Enable = "on";
                app.IntervalButton.BackgroundColor = green;
            else
                app.IntervalButton.Enable = "off";
                app.IntervalButton.BackgroundColor = red;
            end

            if isfield(status, 'classification') && status.classification == true
                app.ClassificationButton.Enable = "on";
                app.ClassificationButton.BackgroundColor = green;
            else
                app.ClassificationButton.Enable = "off";
                app.ClassificationButton.BackgroundColor = red;
            end

            if isfield(status, 'drift') && status.drift == true
                app.DriftButton.Enable = "on";
                app.DriftButton.BackgroundColor = green;
            else
                app.DriftButton.Enable = "off";
                app.DriftButton.BackgroundColor = red;
            end

            if isfield(status, 'quantification') && status.quantification == true
                app.QuantificationButton.Enable = "on";
                app.QuantificationButton.BackgroundColor = green;
            else
                app.QuantificationButton.Enable = "off";
                app.QuantificationButton.BackgroundColor = red;
            end

            if isfield(status, 'results') && status.results == true
                app.ResultsButton.Enable = "on";
                app.ResultsButton.BackgroundColor = green;
            else
                app.ResultsButton.Enable = "off";
                app.ResultsButton.BackgroundColor = red;
            end
        end


        function check_for_update_fun(app,state)
            if not(exist("state","var"))
                state=false;
            end
            try
                % Define the owner and repo for your GitHub repository

                owner = 'GeoSebastianStumpf';
                repo = 'ICP-Base';
                url = sprintf('https://api.github.com/repos/%s/%s/releases', owner, repo);

                % Make the GET request to the GitHub API without authentication
                releaseData = webread(url);

                % If you want to extract specific fields, such as the tag name:
                if ~isempty(releaseData)
                    latestReleaseTag = releaseData(1).tag_name; % The first release in the array (latest)
                    disp(['Latest release tag: ' latestReleaseTag]);

                    if not(strcmp(latestReleaseTag,app.CurrentReleaseTag))

                        message = sprintf(['A new version of ICP-Base is available: <b>%s</b><br><br>'...
                            'You can download it from the following link: <a href="%s">https://github.com/GeoSebastianStumpf/ICP-Base/releases/latest</a>'], ...
                            latestReleaseTag, 'https://github.com/GeoSebastianStumpf/ICP-Base/releases/latest');

                        uialert(app.UIFigure,message,'New Version Available','Interpreter','html','Icon','info');
                    elseif state==true
                        message = sprintf('You have the latest version of ICP-Base installed: <b>%s</b><br><br>' , ...
                            latestReleaseTag);
                        uialert(app.UIFigure,message,'Latest Version Installed','Interpreter','html','Icon','success');

                    end

                else
                    disp('No releases found.');
                end
            catch ME
                if contains(ME.message, 'timeout') || contains(ME.message, 'Unable to resolve host') || contains(ME.message, 'Could not access server. https://api.github.com/repos/GeoSebastianStumpf/ICP-Base/releases.')
                    disp('New Version Check Failed - Unable to connect to GitHub. Please check your internet connection and try again.');
                elseif contains(ME.message, '404')
                    disp('New Version Check Failed - Repository not found. Please check the repository details.');
                else
                    DisplayError(ME,'Startup New Version Check Failed')
                end

            end
        end


        function Base_Table = build_table_from_base(app)

            allProjects = {};
            allSessions = {};
            allSpotnames = {};
            alldates = {};
            allSamples = {};
            allMaterials = {};
            allTypes = {};
            allRefMaterials = {};

            allIntStdElements = {};
            allIntStdValues = {};
            allIntStdOxValues = {};
            allFeRatios = {};
            allIntTimePoints = {};
            allbracket_id = {};
            allQuantifiedWith = {};
            allusedBracket_id = {};
            allIntStdElements_Incl = {};
            allIntStdValues_Incl = {};

            allSensitivitiesRows = {};
            allmatrixcompRows = {};
            allinclcompRows = {};
            allmatrixlodRows = {};
            allincllodRows = {};


            for i = 1:numel(app.base)
                projectName = app.base(i).project_name;
                for j = 1:numel(app.base(i).project)

                    session = app.base(i).project(j).session;

                    sessionName = char(session.session_name);

                    if isfield(session, 'config') && isfield(session.config, 'date')
                        sessiondate = session.config.date;
                    else
                        sessiondate = [];
                    end

                    spotnames = session.spotname;
                    nSpots = numel(spotnames);

                    % Handle missing fields for sample and material
                    if isfield(session.spot, 'sample') && ~isempty(session.spot.sample)
                        sample = session.spot.sample;
                    else
                        sample = repmat({''}, nSpots, 1);
                    end

                    if isfield(session.spot, 'material') && ~isempty(session.spot.material)
                        material = session.spot.material;
                    else
                        material = repmat({''}, nSpots, 1);
                    end

                    if isfield(session.spot, 'type')
                        type = session.spot.type;
                    else
                        type = repmat('', nSpots, 1);
                    end

                    if isfield(session.spot, 'referencematerial')
                        referencematerial = session.spot.referencematerial;
                    else
                        referencematerial = repmat('', nSpots, 1);
                    end

                    if isfield(session.spot, 'bracket_id')
                        bracket_id = session.spot.bracket_id;
                    else
                        bracket_id = repmat('', nSpots, 1);
                    end

                    if isfield(session.spot, 'integer_time_points')
                        integer_time_points = session.spot.integer_time_points;
                    else
                        integer_time_points = repmat('', nSpots, 1);
                    end

                    prm_indices = strcmp(session.spot.type, "PRM");
                    prm_materials = session.spot.referencematerial(prm_indices);
                    unique_PRMs = unique(prm_materials);

                    uniqueBracketIds = unique(session.spot.bracket_id);
                    uniqueBracketIds = uniqueBracketIds(uniqueBracketIds ~= 0);


                    if isfield(session.spot, 'internal_standard')
                        internal_standard_element = session.spot.internal_standard;
                    else

                        internal_standard_element = cell(numel(uniqueBracketIds), numel(unique_PRMs));

                        for k = 1:numel(uniqueBracketIds)
                            for l = 1:numel(unique_PRMs)
                                internal_standard_element{k, l} = repmat({''}, nSpots, 1);
                            end
                        end

                    end

                    if isfield(session.spot, 'internal_standard_mass_fraction')
                        internal_standard_value = session.spot.internal_standard_mass_fraction;
                    else

                        internal_standard_value = cell(numel(uniqueBracketIds), numel(unique_PRMs));

                        for k = 1:numel(uniqueBracketIds)
                            for l = 1:numel(unique_PRMs)
                                internal_standard_value{k, l} = repmat({''}, nSpots, 1);
                            end
                        end

                    end

                    if isfield(session.spot, 'internal_standard_oxide_wt')
                        internal_standard_value_ox = session.spot.internal_standard_oxide_wt;
                    else

                        internal_standard_value_ox = cell(numel(uniqueBracketIds), numel(unique_PRMs));

                        for k = 1:numel(uniqueBracketIds)
                            for l = 1:numel(unique_PRMs)
                                internal_standard_value_ox{k, l} = repmat({''}, nSpots, 1);
                            end
                        end

                    end

                    if isfield(session.spot, 'Fe_ratio_for_total_oxide')
                        Fe_ratio_for_total_oxide = session.spot.Fe_ratio_for_total_oxide;
                    else

                        Fe_ratio_for_total_oxide = cell(numel(uniqueBracketIds), numel(unique_PRMs));

                        for k = 1:numel(uniqueBracketIds)
                            for l = 1:numel(unique_PRMs)
                                Fe_ratio_for_total_oxide{k, l} = repmat({''}, nSpots, 1);
                            end
                        end

                    end

                    if isfield(session.spot, 'sensitivities')
                        sensitivities = session.spot.sensitivities;
                    else

                        sensitivities = cell(numel(uniqueBracketIds), numel(unique_PRMs));

                        analyteNames = session.spot.analytes; % 42x1 cell array
                        analyteNames = strtrim(string(analyteNames)); % Convert to string and trim spaces

                        for k = 1:numel(uniqueBracketIds)
                            for l = 1:numel(unique_PRMs)
                                sensitivities{k, l} = table('Size', [nSpots, numel(analyteNames)], ...
                                    'VariableTypes', repmat("double", 1, numel(analyteNames)), ...
                                    'VariableNames', analyteNames);
                            end
                        end
                    end

                    if isfield(session.spot, 'matrix_composition')
                        matrix_composition = session.spot.matrix_composition;
                    else

                        matrix_composition = cell(numel(uniqueBracketIds), numel(unique_PRMs));

                        analyteNames = session.spot.analytes; % 42x1 cell array
                        analyteNames = strtrim(string(analyteNames)); % Convert to string and trim spaces

                        for k = 1:numel(uniqueBracketIds)
                            for l = 1:numel(unique_PRMs)
                                matrix_composition{k, l} = table('Size', [nSpots, numel(analyteNames)], ...
                                    'VariableTypes', repmat("double", 1, numel(analyteNames)), ...
                                    'VariableNames', analyteNames);
                            end
                        end
                    end

                    if isfield(session.spot, 'matrix_limits_of_detection')
                        matrix_limits_of_detection = session.spot.matrix_limits_of_detection;
                    else

                        matrix_limits_of_detection = cell(numel(uniqueBracketIds), numel(unique_PRMs));

                        analyteNames = session.spot.analytes; % 42x1 cell array
                        analyteNames = strtrim(string(analyteNames)); % Convert to string and trim spaces

                        for k = 1:numel(uniqueBracketIds)
                            for l = 1:numel(unique_PRMs)
                                matrix_limits_of_detection{k, l} = table('Size', [nSpots, numel(analyteNames)], ...
                                    'VariableTypes', repmat("double", 1, numel(analyteNames)), ...
                                    'VariableNames', analyteNames);
                            end
                        end
                    end


                    %%%%%%%%%%

                    if isfield(session.spot, 'internal_standard_inclusion')
                        internal_standard_inclusion = session.spot.internal_standard_inclusion;
                    else

                        internal_standard_inclusion = cell(numel(uniqueBracketIds), numel(unique_PRMs));

                        for k = 1:numel(uniqueBracketIds)
                            for l = 1:numel(unique_PRMs)
                                internal_standard_inclusion{k, l} = repmat({''}, nSpots, 1);
                            end
                        end

                    end

                    if isfield(session.spot, 'internal_standard_mass_fraction_inclusion')
                        internal_standard_mass_fraction_inclusion = session.spot.internal_standard_mass_fraction_inclusion;
                    else

                        internal_standard_mass_fraction_inclusion = cell(numel(uniqueBracketIds), numel(unique_PRMs));

                        for k = 1:numel(uniqueBracketIds)
                            for l = 1:numel(unique_PRMs)
                                internal_standard_mass_fraction_inclusion{k, l} = repmat({''}, nSpots, 1);
                            end
                        end

                    end


                    if isfield(session.spot, 'inclusion_composition')
                        inclusion_composition = session.spot.inclusion_composition;
                    else

                        inclusion_composition = cell(numel(uniqueBracketIds), numel(unique_PRMs));

                        analyteNames = session.spot.analytes; % 42x1 cell array
                        analyteNames = strtrim(string(analyteNames)); % Convert to string and trim spaces

                        for k = 1:numel(uniqueBracketIds)
                            for l = 1:numel(unique_PRMs)
                                inclusion_composition{k, l} = table('Size', [nSpots, numel(analyteNames)], ...
                                    'VariableTypes', repmat("double", 1, numel(analyteNames)), ...
                                    'VariableNames', analyteNames);
                            end
                        end
                    end

                    if isfield(session.spot, 'inclusion_limits_of_detection')
                        inclusion_limits_of_detection = session.spot.inclusion_limits_of_detection;
                    else

                        inclusion_limits_of_detection = cell(numel(uniqueBracketIds), numel(unique_PRMs));

                        analyteNames = session.spot.analytes; % 42x1 cell array
                        analyteNames = strtrim(string(analyteNames)); % Convert to string and trim spaces

                        for k = 1:numel(uniqueBracketIds)
                            for l = 1:numel(unique_PRMs)
                                inclusion_limits_of_detection{k, l} = table('Size', [nSpots, numel(analyteNames)], ...
                                    'VariableTypes', repmat("double", 1, numel(analyteNames)), ...
                                    'VariableNames', analyteNames);
                            end
                        end
                    end


                    % Now, for each combination of unique_PRM and uniqueBracketId, duplicate the session data
                    for p = 1:numel(unique_PRMs)
                        for b = 1:numel(uniqueBracketIds)
                            for k = 1:nSpots
                                allProjects{end+1,1} = projectName;


                                allSessions{end+1,1} = sessionName;
                                alldates{end+1,1} = sessiondate;
                                allSpotnames{end+1,1} = spotnames{k};
                                allSamples{end+1,1} = sample{k};
                                allMaterials{end+1,1} = material{k};
                                allTypes{end+1,1} = type(k);
                                allRefMaterials{end+1,1} = referencematerial(k);
                                allIntTimePoints{end+1,1} = integer_time_points(k);
                                allbracket_id{end+1,1} = bracket_id(k);


                                allIntStdElements{end+1,1} = internal_standard_element{b,p}{k};
                                allIntStdValues{end+1,1} = internal_standard_value{b,p}{k};
                                allIntStdOxValues{end+1,1} = internal_standard_value_ox{b,p}{k};
                                allFeRatios{end+1,1} = Fe_ratio_for_total_oxide{b,p}{k};

                                allIntStdElements_Incl{end+1,1} = internal_standard_inclusion{b,p}{k};
                                allIntStdValues_Incl{end+1,1} = internal_standard_mass_fraction_inclusion{b,p}{k};

                                allQuantifiedWith{end+1,1} = unique_PRMs(p);
                                allusedBracket_id{end+1,1} = uniqueBracketIds(b);

                            end

                            allSensitivitiesRows{end+1,1} = sensitivities{b,p};

                            allmatrixcompRows{end+1,1} = matrix_composition{b,p};
                            allinclcompRows{end+1,1} = inclusion_composition{b,p};
                            allmatrixlodRows{end+1,1} = matrix_limits_of_detection{b,p};
                            allincllodRows{end+1,1} = inclusion_limits_of_detection{b,p};

                        end
                    end
                end
            end

            alldates(cellfun(@isnumeric, alldates)) = {''};

            % Create table
            Metadata_Table = table(allProjects, allSessions, alldates, allSpotnames, ...
                allSamples, allMaterials, allTypes, allRefMaterials, ...
                allIntTimePoints, allbracket_id, ...
                allIntStdElements, allIntStdValues, allIntStdOxValues, allFeRatios,  ...
                allIntStdElements_Incl, allIntStdValues_Incl,...
                allQuantifiedWith, allusedBracket_id, ...
                'VariableNames', {'Project', 'Session', 'Date', 'Spotname', ...
                'Sample', 'Material', 'Type', 'Reference_Material', ...
                'Integer_Time_Points', 'bracket_id', ...
                'Internal_Standard_Element', 'Internal_Standard_Value', ...
                'Internal_Standard_Oxide_Value', 'Fe_Ratio_For_Total_Oxide', ...
                'Internal_Standard_Element_Inclusion', 'Internal_Standard_Value_Inclusion', ...
                'Quantified_with', 'used_bracket_id'});


            Metadata_Table.Date_Str = datetime(Metadata_Table.Date, 'InputFormat', 'yyyyMMDD');
            Metadata_Table.Date_Num = datenum(Metadata_Table.Date_Str);


            %%%%%%%%%%% Sensitivities %%%%%%%%%%%
            % Step 1: Get all unique headers from all tables
            allHeadersCell = cellfun(@(t) t.Properties.VariableNames, allSensitivitiesRows, 'UniformOutput', false);
            allHeaders = unique([allHeadersCell{:}]);

            % Step 2: Sort headers based on numeric values in their names
            numericSort = @(header) sscanf(header, '%*[^0-9]%d'); % Extract numeric part
            [~, sortIdx] = sort(cellfun(numericSort, allHeaders));
            sortedHeaders = allHeaders(sortIdx);

            % Step 2: Process all tables using cellfun
            allSensitivitiesRows = cellfun(@(t) addMissingColumns(t, sortedHeaders), allSensitivitiesRows, 'UniformOutput', false);
            % Step 3: Concatenate all tables vertically
            allSensitivitiesTable = vertcat(allSensitivitiesRows{:});
            allSensitivitiesTable = addvars(allSensitivitiesTable, repmat({'Sensitivities'}, height(allSensitivitiesTable), 1), ...
                'Before', 1, 'NewVariableNames', 'Data_Source');

            %%%%%%%%%%% Matrix %%%%%%%%%%%
            % Step 1: Get all unique headers from all tables
            allHeadersCell = cellfun(@(t) t.Properties.VariableNames, allmatrixcompRows, 'UniformOutput', false);
            allHeaders = unique([allHeadersCell{:}]);

            % Step 2: Sort headers based on numeric values in their names
            numericSort = @(header) sscanf(header, '%*[^0-9]%d'); % Extract numeric part
            [~, sortIdx] = sort(cellfun(numericSort, allHeaders));
            sortedHeaders = allHeaders(sortIdx);

            % Step 2: Process all tables using cellfun
            allmatrixcompRows = cellfun(@(t) addMissingColumns(t, sortedHeaders), allmatrixcompRows, 'UniformOutput', false);
            % Step 3: Concatenate all tables vertically
            allmatrixcompTable = vertcat(allmatrixcompRows{:});
            allmatrixcompTable = addvars(allmatrixcompTable, repmat({'Matrix_Composition'}, height(allmatrixcompTable), 1), ...
             'Before', 1, 'NewVariableNames', 'Data_Source');

            % Step 1: Get all unique headers from all tables
            allHeadersCell = cellfun(@(t) t.Properties.VariableNames, allmatrixlodRows, 'UniformOutput', false);
            allHeaders = unique([allHeadersCell{:}]);

            % Step 2: Sort headers based on numeric values in their names
            numericSort = @(header) sscanf(header, '%*[^0-9]%d'); % Extract numeric part
            [~, sortIdx] = sort(cellfun(numericSort, allHeaders));
            sortedHeaders = allHeaders(sortIdx);

            % Step 2: Process all tables using cellfun
            allmatrixlodRows = cellfun(@(t) addMissingColumns(t, sortedHeaders), allmatrixlodRows, 'UniformOutput', false);
            % Step 3: Concatenate all tables vertically
            allmatrixlodTable = vertcat(allmatrixlodRows{:});
            allmatrixlodTable = addvars(allmatrixlodTable, repmat({'Matrix_LOD'}, height(allmatrixlodTable), 1), ...
             'Before', 1, 'NewVariableNames', 'Data_Source');

            %%%%%%%%%%% Inclusion %%%%%%%%%%%
            % Step 1: Get all unique headers from all tables
            allHeadersCell = cellfun(@(t) t.Properties.VariableNames, allincllodRows, 'UniformOutput', false);
            allHeaders = unique([allHeadersCell{:}]);

                        % Step 2: Sort headers based on numeric values in their names
            numericSort = @(header) sscanf(header, '%*[^0-9]%d'); % Extract numeric part
            [~, sortIdx] = sort(cellfun(numericSort, allHeaders));
            sortedHeaders = allHeaders(sortIdx);


            % Step 2: Process all tables using cellfun
            allincllodRows = cellfun(@(t) addMissingColumns(t, sortedHeaders), allincllodRows, 'UniformOutput', false);
            % Step 3: Concatenate all tables vertically
            allinclusionlodTable = vertcat(allincllodRows{:});
            allinclusionlodTable = addvars(allinclusionlodTable, repmat({'Inclusion_LOD'}, height(allinclusionlodTable), 1), ...
             'Before', 1, 'NewVariableNames', 'Data_Source');

            % Step 1: Get all unique headers from all tables
            allHeadersCell = cellfun(@(t) t.Properties.VariableNames, allinclcompRows, 'UniformOutput', false);
            allHeaders = unique([allHeadersCell{:}]);

                        % Step 2: Sort headers based on numeric values in their names
            numericSort = @(header) sscanf(header, '%*[^0-9]%d'); % Extract numeric part
            [~, sortIdx] = sort(cellfun(numericSort, allHeaders));
            sortedHeaders = allHeaders(sortIdx);


            % Step 2: Process all tables using cellfun
            allinclcompRows = cellfun(@(t) addMissingColumns(t, sortedHeaders), allinclcompRows, 'UniformOutput', false);
            % Step 3: Concatenate all tables vertically
            allinclusioncompTable = vertcat(allinclcompRows{:});
            allinclusioncompTable = addvars(allinclusioncompTable, repmat({'Inclusion_Composition'}, height(allinclusioncompTable), 1), ...
             'Before', 1, 'NewVariableNames', 'Data_Source');

            % Helper function to add missing columns and reorder
            function tableWithAllColumns = addMissingColumns(inputTable, targetHeaders)
                % Create a table with zeros for all missing columns
                missingCols = setdiff(targetHeaders, inputTable.Properties.VariableNames, 'stable');
                missingData = zeros(height(inputTable), numel(missingCols));
                missingTable = array2table(missingData, 'VariableNames', missingCols);

                % Combine existing table with missing columns and reorder
                tableWithAllColumns = [inputTable missingTable];
                tableWithAllColumns = tableWithAllColumns(:, targetHeaders);
            end


            % concatenate all the tables
            Sensitivities_Table = [Metadata_Table, allSensitivitiesTable];

                        % Find the index of 'Data_Source'
            colIdx = find(strcmp(Sensitivities_Table.Properties.VariableNames, 'Data_Source'));
            % Extract the numeric data after 'Data_Source'
            numericData = Sensitivities_Table{:, colIdx+1:end};
            % Find rows where all values after 'Data_Source' are zero
            rowsToRemove = all(numericData == 0, 2);
            % Keep only rows that do NOT have all zeros after 'Data_Source'
            Sensitivities_Table = Sensitivities_Table(~rowsToRemove, :);

            %%%%%%%%%%%%%%%

            Matrix_Comp_Table = [Metadata_Table, allmatrixcompTable];

            % get rid of the zero rows
            % Find the index of 'Data_Source'
            colIdx = find(strcmp(Matrix_Comp_Table.Properties.VariableNames, 'Data_Source'));
            % Extract the numeric data after 'Data_Source'
            numericData = Matrix_Comp_Table{:, colIdx+1:end};
            % Find rows where all values after 'Data_Source' are zero
            rowsToRemove = all(numericData == 0, 2);
            % Keep only rows that do NOT have all zeros after 'Data_Source'
            Matrix_Comp_Table = Matrix_Comp_Table(~rowsToRemove, :);

            %%%%%%%%%%%%%%%
            Incl_Comp_Table = [Metadata_Table, allinclusioncompTable];

            % get rid of the zero rows
            % Find the index of 'Data_Source'
            colIdx = find(strcmp(Incl_Comp_Table.Properties.VariableNames, 'Data_Source'));
            % Extract the numeric data after 'Data_Source'
            numericData = Incl_Comp_Table{:, colIdx+1:end};
            % Find rows where all values after 'Data_Source' are zero
            rowsToRemove = all(numericData == 0, 2);
            % Keep only rows that do NOT have all zeros after 'Data_Source'
            Incl_Comp_Table = Incl_Comp_Table(~rowsToRemove, :);

            %%%%%%%%%%%%%%

            Matrix_LOD_Table = [Metadata_Table, allmatrixlodTable];

            % get rid of the zero rows
            % Find the index of 'Data_Source'
            colIdx = find(strcmp(Matrix_LOD_Table.Properties.VariableNames, 'Data_Source'));
            % Extract the numeric data after 'Data_Source'
            numericData = Matrix_LOD_Table{:, colIdx+1:end};
            % Find rows where all values after 'Data_Source' are zero
            rowsToRemove = all(numericData == 0, 2);
            % Keep only rows that do NOT have all zeros after 'Data_Source'
            Matrix_LOD_Table = Matrix_LOD_Table(~rowsToRemove, :);

            %%%%%%%%%%%%%%%%
            Incl_LOD_Table = [Metadata_Table, allinclusionlodTable];

            % get rid of the zero rows
            % Find the index of 'Data_Source'
            colIdx = find(strcmp(Incl_LOD_Table.Properties.VariableNames, 'Data_Source'));
            % Extract the numeric data after 'Data_Source'
            numericData = Incl_LOD_Table{:, colIdx+1:end};
            % Find rows where all values after 'Data_Source' are zero
            rowsToRemove = all(numericData == 0, 2);
            % Keep only rows that do NOT have all zeros after 'Data_Source'
            Incl_LOD_Table = Incl_LOD_Table(~rowsToRemove, :);


            % concatenate those tables
            %Base_Table = vertcat(Sensitivities_Table, Matrix_Comp_Table, Matrix_LOD_Table, Incl_Comp_Table, Incl_LOD_Table);

            Base_Table = vertcat(Matrix_Comp_Table, Matrix_LOD_Table, Incl_Comp_Table, Incl_LOD_Table);

            Base_Table.Sample_Material_DataSource = join([Base_Table.Sample Base_Table.Material Base_Table.Data_Source],' ');
            Base_Table.SRM_Check = join([cellfun(@char, Base_Table.Reference_Material, 'UniformOutput', false) cellfun(@char, Base_Table.Type, 'UniformOutput', false) Base_Table.Data_Source],' ');


            % %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            % 
            % % this shit needs to be redone and rethought
            % %its not really possible to get rid of the masses
            % % right now i am only doing it for one table
            % 
            % % List of preferred isotopes
            % preferred = {'Ca43', 'Br79', 'Mg25', 'Mo95', 'Ni61', 'Pb207', 'S34', 'Si29'};
            % preferred_elements = regexprep(preferred, '\d+', '');
            % 
            % % Get original column names
            % colnames = Base_Table.Properties.VariableNames;
            % 
            % % Remove numbers from column names
            % elements = regexprep(colnames, '\d+', '');
            % 
            % % Find unique elements
            % [unique_elements, ~, idx] = unique(elements, 'stable');
            % 
            % % Prepare new table
            % newTable = table();
            % 
            % for i = 1:numel(unique_elements)
            %     elem = unique_elements{i};
            %     cols = find(strcmp(elements, elem));
            %     % Find preferred isotope for this element
            %     preferred_col = [];
            %     for p = 1:numel(preferred)
            %         if startsWith(preferred{p}, elem)
            %             idx_pref = find(strcmp(colnames, preferred{p}));
            %             if ~isempty(idx_pref) && ismember(idx_pref, cols)
            %                 preferred_col = idx_pref;
            %                 break;
            %             end
            %         end
            %     end
            %     if isempty(preferred_col)
            %         use_col = cols(1);
            %     else
            %         use_col = preferred_col;
            %     end
            %     % Add 'Matrix_' prefix to the element name
            %     newTable.(elem) = Base_Table{:, use_col};
            % end
            % 
            % Base_Table = newTable;
            % %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%

            Base_Table.Type = cellfun(@char, Base_Table.Type, 'UniformOutput', false);
            Base_Table.Material = cellfun(@char, Base_Table.Material, 'UniformOutput', false);
            Base_Table.Reference_Material = cellfun(@char, Base_Table.Reference_Material, 'UniformOutput', false);
            Base_Table.Quantified_with = cellfun(@char, Base_Table.Quantified_with, 'UniformOutput', false);


            % Identify numeric columns in the table
            numericCols = varfun(@isnumeric, Base_Table, 'OutputFormat', 'uniform');

            % Replace 0 with NaN only in numeric columns
            Base_Table{:, numericCols}(Base_Table{:, numericCols} == 0) = NaN;

        end

        % function Base_Table = build_table_from_base(app)
        % 
        %     allProjects = {};
        %     allSessions = {};
        %     allSpotnames = {};
        %     alldates = {};
        %     allSamples = {};
        %     allMaterials = {};
        %     allTypes = {};
        %     allRefMaterials = {};
        % 
        %     allIntStdElements = {};
        %     allIntStdValues = {};
        %     allIntStdOxValues = {};
        %     allFeRatios = {};
        %     allIntTimePoints = {};
        %     allbracket_id = {};
        %     allQuantifiedWith = {};
        %     allusedBracket_id = {};
        %     allIntStdElements_Incl = {};
        %     allIntStdValues_Incl = {};
        % 
        %     allSensitivitiesRows = {};
        %     allmatrixcompRows = {};
        %     allinclcompRows = {};
        %     allmatrixlodRows = {};
        %     allincllodRows = {};
        % 
        % 
        % 
        %     for i = 1:numel(app.base)
        %         projectName = app.base(i).project_name;
        %         for j = 1:numel(app.base(i).project)
        % 
        %             session = app.base(i).project(j).session;
        % 
        %             sessionName = char(session.session_name);
        % 
        %             if isfield(session, 'config') && isfield(session.config, 'date')
        %                 sessiondate = session.config.date;
        %             else
        %                 sessiondate = [];
        %             end
        % 
        %             spotnames = session.spotname;
        %             nSpots = numel(spotnames);
        % 
        %             % Handle missing fields for sample and material
        %             if isfield(session.spot, 'sample') && ~isempty(session.spot.sample)
        %                 sample = session.spot.sample;
        %             else
        %                 sample = repmat({''}, nSpots, 1);
        %             end
        % 
        %             if isfield(session.spot, 'material') && ~isempty(session.spot.material)
        %                 material = session.spot.material;
        %             else
        %                 material = repmat({''}, nSpots, 1);
        %             end
        % 
        %             if isfield(session.spot, 'type')
        %                 type = session.spot.type;
        %             else
        %                 type = repmat('', nSpots, 1);
        %             end
        % 
        %             if isfield(session.spot, 'referencematerial')
        %                 referencematerial = session.spot.referencematerial;
        %             else
        %                 referencematerial = repmat('', nSpots, 1);
        %             end
        % 
        %             if isfield(session.spot, 'bracket_id')
        %                 bracket_id = session.spot.bracket_id;
        %             else
        %                 bracket_id = repmat('', nSpots, 1);
        %             end
        % 
        %             if isfield(session.spot, 'integer_time_points')
        %                 integer_time_points = session.spot.integer_time_points;
        %             else
        %                 integer_time_points = repmat('', nSpots, 1);
        %             end
        % 
        %             prm_indices = strcmp(session.spot.type, "PRM");
        %             prm_materials = session.spot.referencematerial(prm_indices);
        %             unique_PRMs = unique(prm_materials);
        % 
        %             uniqueBracketIds = unique(session.spot.bracket_id);
        %             uniqueBracketIds = uniqueBracketIds(uniqueBracketIds ~= 0);
        % 
        % 
        %             if isfield(session.spot, 'internal_standard')
        %                 internal_standard_element = session.spot.internal_standard;
        %             else
        % 
        %                 internal_standard_element = cell(numel(uniqueBracketIds), numel(unique_PRMs));
        % 
        %                 for k = 1:numel(uniqueBracketIds)
        %                     for l = 1:numel(unique_PRMs)
        %                         internal_standard_element{k, l} = repmat({''}, nSpots, 1);
        %                     end
        %                 end
        % 
        %             end
        % 
        %             if isfield(session.spot, 'internal_standard_mass_fraction')
        %                 internal_standard_value = session.spot.internal_standard_mass_fraction;
        %             else
        % 
        %                 internal_standard_value = cell(numel(uniqueBracketIds), numel(unique_PRMs));
        % 
        %                 for k = 1:numel(uniqueBracketIds)
        %                     for l = 1:numel(unique_PRMs)
        %                         internal_standard_value{k, l} = repmat({''}, nSpots, 1);
        %                     end
        %                 end
        % 
        %             end
        % 
        %             if isfield(session.spot, 'internal_standard_oxide_wt')
        %                 internal_standard_value_ox = session.spot.internal_standard_oxide_wt;
        %             else
        % 
        %                 internal_standard_value_ox = cell(numel(uniqueBracketIds), numel(unique_PRMs));
        % 
        %                 for k = 1:numel(uniqueBracketIds)
        %                     for l = 1:numel(unique_PRMs)
        %                         internal_standard_value_ox{k, l} = repmat({''}, nSpots, 1);
        %                     end
        %                 end
        % 
        %             end
        % 
        %             if isfield(session.spot, 'Fe_ratio_for_total_oxide')
        %                 Fe_ratio_for_total_oxide = session.spot.Fe_ratio_for_total_oxide;
        %             else
        % 
        %                 Fe_ratio_for_total_oxide = cell(numel(uniqueBracketIds), numel(unique_PRMs));
        % 
        %                 for k = 1:numel(uniqueBracketIds)
        %                     for l = 1:numel(unique_PRMs)
        %                         Fe_ratio_for_total_oxide{k, l} = repmat({''}, nSpots, 1);
        %                     end
        %                 end
        % 
        %             end
        % 
        %             if isfield(session.spot, 'sensitivities')
        %                 sensitivities = session.spot.sensitivities;
        %             else
        % 
        %                 sensitivities = cell(numel(uniqueBracketIds), numel(unique_PRMs));
        % 
        %                 analyteNames = session.spot.analytes; % 42x1 cell array
        %                 analyteNames = strtrim(string(analyteNames)); % Convert to string and trim spaces
        % 
        %                 for k = 1:numel(uniqueBracketIds)
        %                     for l = 1:numel(unique_PRMs)
        %                         sensitivities{k, l} = table('Size', [nSpots, numel(analyteNames)], ...
        %                             'VariableTypes', repmat("double", 1, numel(analyteNames)), ...
        %                             'VariableNames', analyteNames);
        %                     end
        %                 end
        %             end
        % 
        %             if isfield(session.spot, 'matrix_composition')
        %                 matrix_composition = session.spot.matrix_composition;
        %             else
        % 
        %                 matrix_composition = cell(numel(uniqueBracketIds), numel(unique_PRMs));
        % 
        %                 analyteNames = session.spot.analytes; % 42x1 cell array
        %                 analyteNames = strtrim(string(analyteNames)); % Convert to string and trim spaces
        % 
        %                 for k = 1:numel(uniqueBracketIds)
        %                     for l = 1:numel(unique_PRMs)
        %                         matrix_composition{k, l} = table('Size', [nSpots, numel(analyteNames)], ...
        %                             'VariableTypes', repmat("double", 1, numel(analyteNames)), ...
        %                             'VariableNames', analyteNames);
        %                     end
        %                 end
        %             end
        % 
        %             if isfield(session.spot, 'matrix_limits_of_detection')
        %                 matrix_limits_of_detection = session.spot.matrix_limits_of_detection;
        %             else
        % 
        %                 matrix_limits_of_detection = cell(numel(uniqueBracketIds), numel(unique_PRMs));
        % 
        %                 analyteNames = session.spot.analytes; % 42x1 cell array
        %                 analyteNames = strtrim(string(analyteNames)); % Convert to string and trim spaces
        % 
        %                 for k = 1:numel(uniqueBracketIds)
        %                     for l = 1:numel(unique_PRMs)
        %                         matrix_limits_of_detection{k, l} = table('Size', [nSpots, numel(analyteNames)], ...
        %                             'VariableTypes', repmat("double", 1, numel(analyteNames)), ...
        %                             'VariableNames', analyteNames);
        %                     end
        %                 end
        %             end
        % 
        % 
        %             %%%%%%%%%%
        % 
        %             if isfield(session.spot, 'internal_standard_inclusion')
        %                 internal_standard_inclusion = session.spot.internal_standard_inclusion;
        %             else
        % 
        %                 internal_standard_inclusion = cell(numel(uniqueBracketIds), numel(unique_PRMs));
        % 
        %                 for k = 1:numel(uniqueBracketIds)
        %                     for l = 1:numel(unique_PRMs)
        %                         internal_standard_inclusion{k, l} = repmat({''}, nSpots, 1);
        %                     end
        %                 end
        % 
        %             end
        % 
        %             if isfield(session.spot, 'internal_standard_mass_fraction_inclusion')
        %                 internal_standard_mass_fraction_inclusion = session.spot.internal_standard_mass_fraction_inclusion;
        %             else
        % 
        %                 internal_standard_mass_fraction_inclusion = cell(numel(uniqueBracketIds), numel(unique_PRMs));
        % 
        %                 for k = 1:numel(uniqueBracketIds)
        %                     for l = 1:numel(unique_PRMs)
        %                         internal_standard_mass_fraction_inclusion{k, l} = repmat({''}, nSpots, 1);
        %                     end
        %                 end
        % 
        %             end
        % 
        % 
        %             if isfield(session.spot, 'inclusion_composition')
        %                 inclusion_composition = session.spot.inclusion_composition;
        %             else
        % 
        %                 inclusion_composition = cell(numel(uniqueBracketIds), numel(unique_PRMs));
        % 
        %                 analyteNames = session.spot.analytes; % 42x1 cell array
        %                 analyteNames = strtrim(string(analyteNames)); % Convert to string and trim spaces
        % 
        %                 for k = 1:numel(uniqueBracketIds)
        %                     for l = 1:numel(unique_PRMs)
        %                         inclusion_composition{k, l} = table('Size', [nSpots, numel(analyteNames)], ...
        %                             'VariableTypes', repmat("double", 1, numel(analyteNames)), ...
        %                             'VariableNames', analyteNames);
        %                     end
        %                 end
        %             end
        % 
        %             if isfield(session.spot, 'inclusion_limits_of_detection')
        %                 inclusion_limits_of_detection = session.spot.inclusion_limits_of_detection;
        %             else
        % 
        %                 inclusion_limits_of_detection = cell(numel(uniqueBracketIds), numel(unique_PRMs));
        % 
        %                 analyteNames = session.spot.analytes; % 42x1 cell array
        %                 analyteNames = strtrim(string(analyteNames)); % Convert to string and trim spaces
        % 
        %                 for k = 1:numel(uniqueBracketIds)
        %                     for l = 1:numel(unique_PRMs)
        %                         inclusion_limits_of_detection{k, l} = table('Size', [nSpots, numel(analyteNames)], ...
        %                             'VariableTypes', repmat("double", 1, numel(analyteNames)), ...
        %                             'VariableNames', analyteNames);
        %                     end
        %                 end
        %             end
        % 
        % 
        % 
        % 
        %             % Now, for each combination of unique_PRM and uniqueBracketId, duplicate the session data
        %             for p = 1:numel(unique_PRMs)
        %                 for b = 1:numel(uniqueBracketIds)
        %                     for k = 1:nSpots
        %                         allProjects{end+1,1} = projectName;
        % 
        % 
        %                         allSessions{end+1,1} = sessionName;
        %                         alldates{end+1,1} = sessiondate;
        %                         allSpotnames{end+1,1} = spotnames{k};
        %                         allSamples{end+1,1} = sample{k};
        %                         allMaterials{end+1,1} = material{k};
        %                         allTypes{end+1,1} = type(k);
        %                         allRefMaterials{end+1,1} = referencematerial(k);
        %                         allIntTimePoints{end+1,1} = integer_time_points(k);
        %                         allbracket_id{end+1,1} = bracket_id(k);
        % 
        % 
        %                         allIntStdElements{end+1,1} = internal_standard_element{b,p}{k};
        %                         allIntStdValues{end+1,1} = internal_standard_value{b,p}{k};
        %                         allIntStdOxValues{end+1,1} = internal_standard_value_ox{b,p}{k};
        %                         allFeRatios{end+1,1} = Fe_ratio_for_total_oxide{b,p}{k};
        % 
        %                         allIntStdElements_Incl{end+1,1} = internal_standard_inclusion{b,p}{k};
        %                         allIntStdValues_Incl{end+1,1} = internal_standard_mass_fraction_inclusion{b,p}{k};
        % 
        %                         allQuantifiedWith{end+1,1} = unique_PRMs(p);
        %                         allusedBracket_id{end+1,1} = uniqueBracketIds(b);
        % 
        %                     end
        % 
        %                     allSensitivitiesRows{end+1,1} = sensitivities{b,p};
        % 
        %                     allmatrixcompRows{end+1,1} = matrix_composition{b,p};
        %                     allinclcompRows{end+1,1} = inclusion_composition{b,p};
        %                     allmatrixlodRows{end+1,1} = matrix_limits_of_detection{b,p};
        %                     allincllodRows{end+1,1} = inclusion_limits_of_detection{b,p};
        % 
        %                 end
        %             end
        %         end
        %     end
        % 
        %     alldates(cellfun(@isnumeric, alldates)) = {''};
        % 
        %     % Create table
        %     Metadata_Table = table(allProjects, allSessions, alldates, allSpotnames, ...
        %         allSamples, allMaterials, allTypes, allRefMaterials, ...
        %         allIntTimePoints, allbracket_id, ...
        %         allIntStdElements, allIntStdValues, allIntStdOxValues, allFeRatios,  ...
        %         allIntStdElements_Incl, allIntStdValues_Incl,...
        %         allQuantifiedWith, allusedBracket_id, ...
        %         'VariableNames', {'Project', 'Session', 'Date', 'Spotname', ...
        %         'Sample', 'Material', 'Type', 'Reference_Material', ...
        %         'Integer_Time_Points', 'bracket_id', ...
        %         'Internal_Standard_Element', 'Internal_Standard_Value', ...
        %         'Internal_Standard_Oxide_Value', 'Fe_Ratio_For_Total_Oxide', ...
        %         'Internal_Standard_Element_Inclusion', 'Internal_Standard_Value_Inclusion', ...
        %         'Quantified_with', 'used_bracket_id'});
        % 
        % 
        % 
        %     Metadata_Table.Date_Str = datetime(Metadata_Table.Date, 'InputFormat', 'yyyyMMDD');
        %     Metadata_Table.Date_Num = datenum(Metadata_Table.Date_Str);
        % 
        % 
        %     %%%%%%%%%%% Sensitivities %%%%%%%%%%%
        %     % Step 1: Get all unique headers from all tables
        %     allHeadersCell = cellfun(@(t) t.Properties.VariableNames, allSensitivitiesRows, 'UniformOutput', false);
        %     allHeaders = unique([allHeadersCell{:}]);
        % 
        %     % Step 2: Sort headers based on numeric values in their names
        %     numericSort = @(header) sscanf(header, '%*[^0-9]%d'); % Extract numeric part
        %     [~, sortIdx] = sort(cellfun(numericSort, allHeaders));
        %     sortedHeaders = allHeaders(sortIdx);
        % 
        %     % Step 3: Process all tables using cellfun
        %     allSensitivitiesRows = cellfun(@(t) addMissingColumns(t, sortedHeaders), allSensitivitiesRows, 'UniformOutput', false);
        % 
        %     % Step 4: Add "_sensitivity" suffix to column headers (except 'Data_Source')
        %     allSensitivitiesRows = cellfun(@(t) addSuffixToHeaders(t, '_sensitivity'), allSensitivitiesRows, 'UniformOutput', false);
        % 
        %     % Step 5: Concatenate all tables vertically
        %     allSensitivitiesTable = vertcat(allSensitivitiesRows{:});
        %    % allSensitivitiesTable = addvars(allSensitivitiesTable, repmat({'Sensitivities'}, height(allSensitivitiesTable), 1), ...
        %      %   'Before', 1, 'NewVariableNames', 'Data_Source');
        % 
        %     %%%%%%%%%%% Matrix %%%%%%%%%%%
        %     % Repeat the same steps for Matrix tables
        %     allHeadersCell = cellfun(@(t) t.Properties.VariableNames, allmatrixcompRows, 'UniformOutput', false);
        %     allHeaders = unique([allHeadersCell{:}]);
        %     [~, sortIdx] = sort(cellfun(numericSort, allHeaders));
        %     sortedHeaders = allHeaders(sortIdx);
        %     allmatrixcompRows = cellfun(@(t) addMissingColumns(t, sortedHeaders), allmatrixcompRows, 'UniformOutput', false);
        %     allmatrixcompRows = cellfun(@(t) addSuffixToHeaders(t, '_ppm'), allmatrixcompRows, 'UniformOutput', false);
        %     allmatrixcompTable = vertcat(allmatrixcompRows{:});
        %     %allmatrixcompTable = addvars(allmatrixcompTable, repmat({'Matrix_Composition'}, height(allmatrixcompTable), 1), ...
        %      %   'Before', 1, 'NewVariableNames', 'Data_Source');
        % 
        %     %%%%%%%%%%% LOD %%%%%%%%%%%
        %     % Repeat the same steps for LOD tables
        %     allHeadersCell = cellfun(@(t) t.Properties.VariableNames, allmatrixlodRows, 'UniformOutput', false);
        %     allHeaders = unique([allHeadersCell{:}]);
        %     [~, sortIdx] = sort(cellfun(numericSort, allHeaders));
        %     sortedHeaders = allHeaders(sortIdx);
        %     allmatrixlodRows = cellfun(@(t) addMissingColumns(t, sortedHeaders), allmatrixlodRows, 'UniformOutput', false);
        %     allmatrixlodRows = cellfun(@(t) addSuffixToHeaders(t, '_LOD'), allmatrixlodRows, 'UniformOutput', false);
        %     allmatrixlodTable = vertcat(allmatrixlodRows{:});
        %     %allmatrixlodTable = addvars(allmatrixlodTable, repmat({'Matrix_LOD'}, height(allmatrixlodTable), 1), ...
        %      %   'Before', 1, 'NewVariableNames', 'Data_Source');
        % 
        %     %%%%%%%%%%% Inclusion %%%%%%%%%%%
        %     % Repeat the same steps for Inclusion tables
        %     allHeadersCell = cellfun(@(t) t.Properties.VariableNames, allincllodRows, 'UniformOutput', false);
        %     allHeaders = unique([allHeadersCell{:}]);
        %     [~, sortIdx] = sort(cellfun(numericSort, allHeaders));
        %     sortedHeaders = allHeaders(sortIdx);
        %     allincllodRows = cellfun(@(t) addMissingColumns(t, sortedHeaders), allincllodRows, 'UniformOutput', false);
        %     allincllodRows = cellfun(@(t) addSuffixToHeaders(t, '_incl_LOD'), allincllodRows, 'UniformOutput', false);
        %     allinclusionlodTable = vertcat(allincllodRows{:});
        %     %allinclusionlodTable = addvars(allinclusionlodTable, repmat({'Inclusion_LOD'}, height(allinclusionlodTable), 1), ...
        %      %   'Before', 1, 'NewVariableNames', 'Data_Source');
        % 
        %     % Step 1: Get all unique headers from all tables
        %     allHeadersCell = cellfun(@(t) t.Properties.VariableNames, allinclcompRows, 'UniformOutput', false);
        %     allHeaders = unique([allHeadersCell{:}]);
        %     numericSort = @(header) sscanf(header, '%*[^0-9]%d'); % Extract numeric part
        %     [~, sortIdx] = sort(cellfun(numericSort, allHeaders));
        %     sortedHeaders = allHeaders(sortIdx);
        %     allinclcompRows = cellfun(@(t) addMissingColumns(t, sortedHeaders), allinclcompRows, 'UniformOutput', false);
        %     allinclcompRows = cellfun(@(t) addSuffixToHeaders(t, '_incl_ppm'), allinclcompRows, 'UniformOutput', false);
        %     allinclusioncompTable = vertcat(allinclcompRows{:});
        %     %allinclusioncompTable = addvars(allinclusioncompTable, repmat({'Inclusion_Composition'}, height(allinclusioncompTable), 1), ...
        %      %   'Before', 1, 'NewVariableNames', 'Data_Source');
        % 
        % 
        %     %%%%%%%%%%% Helper Function %%%%%%%%%%%
        %     function t = addSuffixToHeaders(t, suffix)
        %         % Add a suffix to all column headers except 'Data_Source'
        %         headers = t.Properties.VariableNames;
        %         for i = 1:length(headers)
        %             if ~strcmp(headers{i}, 'Data_Source')
        %                 headers{i} = [headers{i}, suffix];
        %             end
        %         end
        %         t.Properties.VariableNames = headers;
        %     end
        %     % Helper function to add missing columns and reorder
        %     function tableWithAllColumns = addMissingColumns(inputTable, targetHeaders)
        %         % Create a table with zeros for all missing columns
        %         missingCols = setdiff(targetHeaders, inputTable.Properties.VariableNames, 'stable');
        %         missingData = zeros(height(inputTable), numel(missingCols));
        %         missingTable = array2table(missingData, 'VariableNames', missingCols);
        % 
        %         % Combine existing table with missing columns and reorder
        %         tableWithAllColumns = [inputTable missingTable];
        %         tableWithAllColumns = tableWithAllColumns(:, targetHeaders);
        %     end
        % 
        % 
        %     % concatenate all the tables
        %     Base_Table = [Metadata_Table, allmatrixcompTable]; % , allinclusioncompTable, allmatrixlodTable, allinclusionlodTable, allSensitivitiesTable
        % 
        %     Base_Table.Sample_Material = join([Base_Table.Sample Base_Table.Material],' ');
        %     Base_Table.SRM_Check = join([cellfun(@char, Base_Table.Reference_Material, 'UniformOutput', false) cellfun(@char, Base_Table.Type, 'UniformOutput', false)],' ');
        % 
        % 
        %     Base_Table.Type = cellfun(@char, Base_Table.Type, 'UniformOutput', false);
        %     Base_Table.Material = cellfun(@char, Base_Table.Material, 'UniformOutput', false);
        %     Base_Table.Reference_Material = cellfun(@char, Base_Table.Reference_Material, 'UniformOutput', false);
        %     Base_Table.Quantified_with = cellfun(@char, Base_Table.Quantified_with, 'UniformOutput', false);
        % 
        % 
        %     % Identify numeric columns in the table
        %     numericCols = varfun(@isnumeric, Base_Table, 'OutputFormat', 'uniform');
        % 
        %     % Replace 0 with NaN only in numeric columns
        %     Base_Table{:, numericCols}(Base_Table{:, numericCols} == 0) = NaN;
        % 
        % end
        % 


    end
    methods (Access = public)

        % this function needs to sort everything back in the base
        function [project_id, session_id] = save_to_base(app, project_id, session, session_id, project_name, settings)
            try
                %% sort in the session using the ids
                %% if the id is a number higher than 1, it will just go to that place

                % if I open the import module from the base, this is not needed
                % because the project_id is sent to the Import_Module
                % if the user decides to input 0 for all the project_ids
                if isequal(project_id, 0) || isempty(project_id)
                    project_id = numel(app.base) + 1;
                end

                % make an empty struct if a new project is created throught the
                % API
                if project_id > numel(app.base)
                    app.base(project_id).project = struct();
                end

                % these are for sorting the sessions back into the right projects
                if numel(app.base) == 0 % completely new base initialzed by API
                    session_id = 1;
                elseif isempty(fieldnames(app.base(project_id).project)) || ~isfield(app.base(project_id).project, 'session') || isempty(app.base(project_id).project)
                    session_id = 1;
                elseif isequal(session_id, 0) || isempty(session_id)
                    session_id = numel(app.base(project_id).project) + 1;
                end

                % save the session at the right place in the base
                app.base(project_id).project(session_id).session = session;

                %% session and project names

                app.base(project_id).project_name = project_name;

                % check if there is a session_name already in the session
                % struct, if not name it New Session
                if ~isfield(app.base(project_id).project(session_id).session, 'session_name') || isempty(app.base(project_id).project(session_id).session.session_name)
                    app.base(project_id).project(session_id).session.session_name = 'New Session';
                end

                %% GUI update
                if isfield(settings, 'ICP_Base_GUI_update_state') && settings.ICP_Base_GUI_update_state == true
                    update_base_GUI(app, project_id)

                    update_progress_buttons(app, project_id, session_id)
                end
            catch ME
                DisplayError(ME,'Error - save_to_base')
            end
        end


    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            movegui(app.UIFigure, 'center');
            if isdeployed %standalone mode.
                try
                    if ispc
                        [~, result] = system('path');
                        app.installpath = char(regexpi(result, 'Path=(.*?);', 'tokens', 'once'));
                    elseif ismac
                        path=ctfroot;
                        disp('ctfroot:')
                        disp(path)

                        path_split=split(path,{'ICP-Base.app'});
                        disp('path_split')
                        disp(path_split)

                        app.installpath=path_split{1};
                        disp('Installation Directory 0:')
                        disp(app.installpath)
                        disp('')

                        if not(exist(fullfile(app.installpath,'SRM_Files','NIST610.csv'),'file'))
                            answer = uiconfirm(app.UIFigure,{'Application directory not found - Select ICP-Base directory'},'Select directory','Options',"OK",'Icon','warning');
                            app.installpath=uigetdir(pwd,'select application directory');
                        end
                    elseif isunix
                        try
                            app.installpath=pwd;
                        catch
                            answer = uiconfirm(app.UIFigure,{'Application directory not found - Select ICP-Base directory'},'Select directory','Options',"OK",'Icon','warning');
                            app.installpath=uigetdir(pwd,'select application directory');

                        end
                    end

                    tmp_path='';

                    if exist(fullfile(app.installpath,'Dev','ICP-BaseWorkingDir'),'file')
                        tmp_path= fileread(fullfile(app.installpath,'Dev','ICP-BaseWorkingDir'));
                        disp('tmp_path 0:')
                        disp(tmp_path)
                        if exist(tmp_path,'dir')
                            try
                                diary(fullfile(tmp_path,'ICP-Base_log.txt'))
                                user_path=tmp_path;
                            catch
                                disp('could not use user_path to create logfile')
                                tmp_path='There was an error logfile could not be created';
                            end
                        end
                    end

                    if isempty(tmp_path) || not(exist(tmp_path,'dir'))

                        user_path=userpath;
                        disp('Userpath -2:')
                        disp(user_path)

                        user_path=replace(user_path,'/MATLAB','');
                        user_path=replace(user_path,'\MATLAB','');
                        disp('Userpath -1:')
                        disp(user_path)
                        user_path=fullfile(user_path,'ICP-Base');

                        disp('Userpath 0:')
                        disp(user_path)


                        if ~exist(user_path,'dir')
                            try
                                disp('Userpath does not exist')
                                [status, msg, ~] = mkdir(user_path);
                                if status==0
                                    answer = uiconfirm(app.UIFigure,{'Working directory not found - Select working directory'},'Select directory','Options',"OK",'Icon','warning');

                                    disp('Userpath folder could not be created:')
                                    disp(msg)
                                    f = figure('Renderer', 'painters', 'Position', [-100 -100 0 0]); %create a dummy figure so that uigetfile doesn't minimize our GUI
                                    user_path=uigetdir(pwd,'select working directory with read/write permission');
                                    delete(f)
                                    %   disp('Userpath folder could not be created')
                                else
                                    disp('Userpath folder created:')
                                    disp(user_path)
                                end

                            catch ME
                                DisplayError(ME,'Startup Error mkdir')

                                if ~exist(user_path,'dir')
                                    answer = uiconfirm(app.UIFigure,{'Working directory not found - Select working directory'},'Select directory','Options',"OK",'Icon','warning');
                                    % uialert(app.UIFigure,'To avoide this message create a folder called ICP-Base in your Documents folder',' Error');
                                    user_path=uigetdir(pwd,'select working directory with read/write permission');
                                end
                            end
                        end
                    end

                    diary(fullfile(user_path,'ICP-Base_log.txt'));

                    disp('Userpath 1:')
                    disp(user_path)

                    disp('Installation Directory:')
                    disp(app.installpath)
                    disp('')

                    disp('tmp_path 1:')
                    disp(tmp_path)
                    disp('')


                    app.WorkingDirectoryEditField.Value=user_path;
                    app.WorkingDirectoryEditField.Tooltip=user_path;

                    cd(user_path);
                    if not(exist(fullfile(app.installpath,'Dev','ICP-BaseWorkingDir'),'file')) || isempty(tmp_path)
                        try
                            dir2exp=fullfile(app.installpath,'Dev','ICP-BaseWorkingDir');
                            disp('dir2exp:')
                            disp(dir2exp)

                            [fileID,errmsg] = fopen(dir2exp,'w');
                            if fileID==-1
                                dir2exp2=fullfile(user_path,'ICP-BaseWorkingDir');
                                [fileID,errmsg] = fopen(dir2exp2,'w');
                                fprintf(fileID,'%s',user_path);
                                fclose(fileID);
                                destination=fullfile(app.installpath,'Dev');
                                [status,msg,~]= movefile(dir2exp2, destination,'f');


                                if status==false
                                    disp('Error msg:')
                                    disp(msg)
                                end
                            else
                                fprintf(fileID,'%s',user_path);
                                fclose(fileID);
                            end
                        catch ME
                            disp('errmsg:')
                            disp(errmsg)

                            DisplayError(ME,'Startup Error ICP-BaseWorkingDir file')

                            ans1= uiconfirm(app.UIFigure, ...
                                {'ICP-BaseWorkingDir file - Permission Error!',...
                                'To fix this Error you have to options:', '-'...
                                '1) Execute the following command in the terminal:',...
                                [ 'chmod -R 755 ' app.installpath ],'-',...
                                '2) Manually add a path to your default working directory folder with read/write permission to the file:'...
                                , fullfile(app.installpath,'Dev','ICP-BaseWorkingDir') },"Permission Error", ...
                                "Icon","warning");
                            disp(ans1)

                        end
                    end
                catch ME
                    DisplayError(ME,'Startup Error 1')
                end

            else
                user_path=fileparts(which('ICP_Base.mlapp'));
                app.installpath=user_path;
                app.WorkingDirectoryEditField.Value=pwd;
                app.WorkingDirectoryEditField.Tooltip=app.WorkingDirectoryEditField.Value;

            end


            %% check for new version
            % check_for_update_fun(app)


        end

        % Button pushed function: AddSessionButton
        function AddSessionButtonPushed(app, event)

            %% get the project_id as the selection of the ProjectTable

            project_id = app.LastSelectedProjectRow;

            if isempty(project_id)
                message = "Select a Project first";
                uialert(app.UIFigure,message,"Warning", ...
                    "Icon","warning");
                return
            end

            %% here the session id is assigned to the new session and then sent to the Import_Module
            % if the field session in DIQES does not yet exist, the first
            % session has id zero. when it is sent back it will change to 1
            % if not(isfield(app.base(project_id).project, 'session'))
            %
            %     session_id = 0;
            %
            % else % the sid is number of sessions + 1 when other sessions are added
            %
            %     session_id = numel(app.base(project_id).project) + 1;
            %
            % end
            %
            % since a new session is added, there is no need to send the
            % whole project, just the ids are sent to the
            % Import_Module


            session_id = 0;
            Import_Module(app, app.base(project_id).project_name, project_id, session_id)
            % app = der Handle von DIQES,
            % was app hier ist, ist sourceapp in Import_Module

        end

        % Button pushed function: ClassificationButton, DriftButton, 
        % ImportButton, IntervalButton, QuantificationButton, ResultsButton
        function ImportButtonPushed(app, event)
            try
                % check if selection is not empty
                if ~isempty(app.SessionTable.Selection) && not(isequal(app.SessionTable.Selection(1),0))

                    % session_id is the selection
                    session_id = app.SessionTable.Selection(1);
                    % the project_id is the last selected project in the
                    % project table
                    project_id = app.LastSelectedProjectRow;

                    switch event.Source.Parent.Parent.Parent.CallbackObject.Text
                        case 'Show Session in Import Module'
                            Import_Module(app, app.base(project_id).project_name, project_id, session_id)
                        case 'Show Session in Interval Module'
                            Interval_Module(app, app.base(project_id).project_name, project_id, session_id)
                        case 'Show Session in Classification Module'
                            Classification_Module(app, app.base(project_id).project_name, project_id, session_id)
                        case 'Show Session in Drift Module'
                            Drift_Module(app, app.base(project_id).project_name, project_id, session_id)
                        case 'Show Session in Quantification Module'
                            Quantification_Module(app, app.base(project_id).project_name, project_id, session_id)
                        case 'Show Session in Results Module'
                            Results_Module(app, app.base(project_id).project_name, project_id, session_id)
                    end

                end

            catch ME
                DisplayError(ME,'Error when proceeding from the module buttons')
            end


        end

        % Cell edit callback: SessionTable
        function SessionTableCellEdit(app, event)

            % get the session table selection
            sel_row_session = app.SessionTable.Selection(1);
            sel_col_session = app.SessionTable.Selection(2);

            % this takes the LastSelectedProjectRow
            if strcmp(app.SessionTable.ColumnName{sel_col_session}, 'Session Name')
                app.base(app.LastSelectedProjectRow).project(sel_row_session).session.session_name = event.NewData;
            elseif strcmp(app.SessionTable.ColumnName{sel_col_session}, 'Comment')
                app.base(app.LastSelectedProjectRow).project(sel_row_session).session.meta.session_comment = event.NewData;
            elseif strcmp(app.SessionTable.ColumnName{sel_col_session}, 'Date YYYYMMDD')
                app.base(app.LastSelectedProjectRow).project(sel_row_session).session.config.date = event.NewData;
            elseif strcmp(app.SessionTable.ColumnName{sel_col_session}, 'Analyst')
                app.base(app.LastSelectedProjectRow).project(sel_row_session).session.config.analyst = event.NewData;
            end

        end

        % Menu selected function: SaveProjectMenu
        function SaveProjectMenuSelected(app, event)
            %% this also needs the project_id to know which project to save

            % if isprop(app,'projectpath') && not(isempty(app.base.projectpath))
            %     app.UIFigure.Name = [app.base.projectpath ' [... Saving Project]'] ;
            %
            %     data2save.project = app.base.project;
            %     data2save.projectpath = app.base.projectpath;
            %
            %     save(app.base.projectpath, 'data2save', '-v7.3');
            %     app.UIFigure.Name = app.base.projectpath;
            %
            % else
            %     SaveProjectasMenuSelected(app)
            % end
            %
            % if isprop(app,'projectpath')
            %
            %     app.UIFigure.Name = app.base.projectpath;
            %
            % end
        end

        % Menu selected function: SaveProjectasMenu
        function SaveProjectasMenuSelected(app, event)


            % f = figure('Renderer', 'painters', 'Position', [-100 -100 0 0]);
            % [filename, filepath] = uiputfile('*.mat', 'Save the project file:');
            % delete(f);
            % figure(app.UIFigure);
            %
            % if not(isequal(filename,0))
            %     FileName = fullfile(filepath, filename);
            %     app.base.projectpath = FileName;
            %     app.UIFigure.Name = [app.base.projectpath ' [... Saving Project]'] ;
            %
            %     data2save.project = app.base.project;
            %     data2save.projectpath = app.base.projectpath;
            %
            %     save(FileName, 'data2save', '-v7.3');
            %     app.UIFigure.Name = app.base.projectpath;
            % end
        end

        % Menu selected function: OpenProjectMenu
        function OpenProjectMenuSelected(app, event)

            %     if isfield(app.base,'projectpath') && not(isempty(app.base.projectpath))
            %         [~, baseFileNameNoExt, ~] = fileparts(app.base.projectpath);
            %         projectname=[' ' baseFileNameNoExt];
            %
            %     else
            %         projectname='';
            %     end
            %     answer = questdlg({['Do you want to save your project' projectname '?']},...
            %         '','Yes','No','Cancel','');
            %     switch answer
            %         case 'Yes'
            %             SaveProjectMenuSelected(app)
            %         case 'Cancel'
            %             return
            %     end
            %
            %
            % f = figure('Renderer', 'painters', 'Position', [-100 -100 0 0]);
            % [file,path]=uigetfile({'*.mat'},"MultiSelect","off", 'Open Project');
            % delete(f);
            % figure(app.UIFigure);
            %
            % if not(isequal(file,0))
            %     data2import = load(fullfile(path,file));
            %
            %     app.base.project = data2import.data2save.project;
            %     app.base.projectpath = data2import.data2save.projectpath;
            % else
            %     return
            % end
            %
            % update_session_table(app)


        end

        % Button pushed function: AddProjectButton
        function AddProjectButtonPushed(app, event)
            %% initializing empty projects in the base
            if not(isfield(app.base, 'project'))

                app.base(1).project = struct();
                app.base(1).project_name = 'New Project';

            else % the entry_id

                entry_id = numel(app.base) + 1;

                app.base(entry_id).project = struct();
                app.base(entry_id).project_name = 'New Project';

            end

            update_project_table(app)
        end

        % Callback function: ProjectTable
        function ProjectTableSelectionChanged(app, event)
            %% updates the session table when a project is selected
            if ~isempty(app.ProjectTable.Selection)
                if not(isempty(app.ProjectTable.Selection(1)))
                    selection = app.ProjectTable.Selection(1);
                else
                    return
                end

                if not(isempty(fieldnames(app.base(selection).project))) && isfield(app.base(selection).project, 'session')
                    update_session_table(app, selection)
                else
                    app.SessionTable.Data = {};
                end
            else
                return
            end

            %% failsafe if a project is clicked on but then it is clicked
            % somewhere in the ProjectTable where there is no project, the
            % selection gets set to [] and the conncection to the project
            % is lost
            % so the last clicked on row is always saved to always get back
            % to the project
            % Check if there is a selection in the ProjectTable
            if isempty(app.ProjectTable.Selection)
                % Use the last stored selection if the current selection is empty
                app.LastSelectedProjectRow = app.LastSelectedProjectRow;
            else
                % Update the last stored selection with the current selection
                app.LastSelectedProjectRow = app.ProjectTable.Selection(1);
            end

            % if Tableselection changed make all buttons just grey

            app.ImportButton.Visible = "off";
            app.IntervalButton.Visible = "off";
            app.ClassificationButton.Visible = "off";
            app.DriftButton.Visible = "off";
            app.QuantificationButton.Visible = "off";
            app.ResultsButton.Visible = "off";

            app.SessionTable.Selection = [];
        end

        % Menu selected function: SaveBaseMenu
        function SaveBaseMenuSelected(app, event)

            if isfield(app.base, 'meta') && isfield(app.base(1).meta, 'basepath') && not(isempty(app.base(1).meta.basepath))
                app.UIFigure.Name = [app.base(1).meta.basepath ' [... Saving Base]'] ;

                data2save.base = app.base;
                %data2save.meta = app.meta;

                save(app.base(1).meta.basepath, 'data2save', '-v7.3');
                app.UIFigure.Name = app.base(1).meta.basepath;

            else
                SaveBaseasMenuSelected(app)
            end

            if isfield(app.base,'meta') && isfield(app.base(1).meta, 'basepath')

                app.UIFigure.Name = app.base(1).meta.basepath;

            end
        end

        % Menu selected function: OpenBaseMenu
        function OpenBaseMenuSelected(app, event)

            if isfield(app.base,'meta') && isfield(app.base(1).meta, 'basepath') && not(isempty(app.base(1).meta.basepath))
                [~, baseFileNameNoExt, ~] = fileparts(app.base(1).meta.basepath);
                basename=[' ' baseFileNameNoExt];

            else
                basename='';
            end
            if ~isempty(app.base)
                answer = questdlg({['Do you want to save this base' basename '?']},...
                    '','Yes','No','Cancel','');
                switch answer
                    case 'Yes'
                        SaveBaseMenuSelected(app)
                    case 'Cancel'
                        return
                end
            end

            f = figure('Renderer', 'painters', 'Position', [-100 -100 0 0]);
            [file,path]=uigetfile({'*.mat'},"MultiSelect","off", 'Open Base');
            delete(f);
            figure(app.UIFigure);

            if not(isequal(file,0))
                data2import = load(fullfile(path,file));

                app.base = data2import.data2save.base;
            else
                return
            end

            update_project_table(app)

        end

        % Cell edit callback: ProjectTable
        function ProjectTableCellEdit(app, event)
            % get the session table selection
            sel_row_project = app.ProjectTable.Selection(1);
            sel_col_project = app.ProjectTable.Selection(2);


            if strcmp(app.ProjectTable.ColumnName{sel_col_project}, 'Project Name')
                app.base(sel_row_project).project_name = event.NewData;
            end
        end

        % Menu selected function: SaveBaseasMenu
        function SaveBaseasMenuSelected(app, event)
            f = figure('Renderer', 'painters', 'Position', [-100 -100 0 0]);
            [filename, filepath] = uiputfile('*.mat', 'Save the base file:');
            delete(f);
            figure(app.UIFigure);

            if not(isequal(filename,0))
                FileName = fullfile(filepath, filename);
                app.base(1).meta.basepath = FileName;
                app.UIFigure.Name = [app.base(1).meta.basepath ' [... Saving Base]'] ;

                data2save.base = app.base;

                save(FileName, 'data2save', '-v7.3');
                app.UIFigure.Name = app.base(1).meta.basepath;
            end
        end

        % Button pushed function: DuplicateProjectButton
        function DuplicateProjectButtonPushed(app, event)
            if ~isempty(app.ProjectTable.Selection)
                %% deleting selected project
                selection = app.ProjectTable.Selection(1);
                app.base(end+1)= app.base(selection);
            else
                return
            end

            update_project_table(app)
        end

        % Button pushed function: DeleteProjectButton
        function DeleteProjectButtonPushed(app, event)
            if ~isempty(app.ProjectTable.Selection)
                %% deleting selected project
                selection = app.ProjectTable.Selection(1);
                app.base(selection) = [];
            else
                return
            end

            update_project_table(app)
        end

        % Callback function: SessionTable
        function SessionTableSelectionChanged(app, event)
            % session_id is the selection

            if ~isempty(app.SessionTable.Selection) && not(isequal(app.SessionTable.Selection(1),0))

                session_id = app.SessionTable.Selection(1);
                % the project_id is the last selected project in the
                % project table
                project_id = app.LastSelectedProjectRow;
                update_progress_buttons(app, project_id, session_id)
            end
        end

        % Button pushed function: SelectButton
        function SelectButtonPushed(app, event)
            f = figure('Renderer', 'painters', 'Position', [-100 -100 0 0]); %create a dummy figure so that uigetfile doesn't minimize our GUI
            path=uigetdir(pwd,'select export directory with read/write permission');
            delete(f)
            figure(app.UIFigure);

            if path==0
                app.WorkingDirectoryEditField.Value=pwd;
            else
                app.WorkingDirectoryEditField.Value=path;
            end
            app.WorkingDirectoryEditField.Tooltip=app.WorkingDirectoryEditField.Value;

            WorkingDirectoryEditFieldValueChanged(app)


        end

        % Value changed function: WorkingDirectoryEditField
        function WorkingDirectoryEditFieldValueChanged(app, event)
            value = app.WorkingDirectoryEditField.Value;
            if not(exist(value,'dir'))
                uiconfirm(app.UIFigure,{'Directory not found - Select working directory'},'Select directory','Options',"OK",'Icon','warning');
                f = figure('Renderer', 'painters', 'Position', [-100 -100 0 0]); %create a dummy figure so that uigetfile doesn't minimize our GUI
                path=uigetdir(pwd,'select working directory with read/write permission');
                delete(f)
                figure(app.UIFigure);

                if ~isequal(path,0)
                    cd(path)
                    app.WorkingDirectoryEditField.Value=path;
                else
                    app.WorkingDirectoryEditField.Value=pwd;
                end
                app.WorkingDirectoryEditField.Tooltip=app.WorkingDirectoryEditField.Value;
                diary('ICP-Base_log.txt');
            else
                diary('ICP-Base_log.txt');
            end
        end

        % Button pushed function: DeleteSessionButton
        function DeleteSessionButtonPushed(app, event)

            if ~isempty(app.SessionTable.Selection)
                %% deleting selected session
                selection = app.SessionTable.Selection(1);

                app.base(app.ProjectTable.Selection(1)).project(selection) = [];

            else
                return
            end

            update_session_table(app, app.ProjectTable.Selection(1))


        end

        % Button pushed function: DuplicateSessionButton
        function DuplicateSessionButtonPushed(app, event)
            if ~isempty(app.SessionTable.Selection)
                %% deleting selected session
                selection = app.SessionTable.Selection(1);

                app.base(app.ProjectTable.Selection(1)).project(end + 1) = app.base(app.ProjectTable.Selection(1)).project(selection);

            else
                return
            end

            update_session_table(app, app.ProjectTable.Selection(1))


        end

        % Button pushed function: SendBasetoPlotModuleButton
        function SendBasetoPlotModuleButtonPushed(app, event)


            Base_Table = build_table_from_base(app);

            %writetable(Base_Table, "C:\Users\Sebastian\Downloads\Base_Table.xlsx")

            % T2=Base_Table(strcmp(Base_Table.Project,'Almirez'),:);
            % T2.Sample_Material_DataSource=join([T2.Sample T2.Material T2.Data_Source],' ');
            % 
            % VisModule(T2)

            VisModule(Base_Table)
            %GeoCPlot(T2)

        end

        % Button pushed function: SendProjecttoPlotModuleButton
        function SendProjecttoPlotModuleButtonPushed(app, event)

        end

        % Button pushed function: SendSessiontoPlotModuleButton
        function SendSessiontoPlotModuleButtonPushed(app, event)

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'ICP-Base';
            app.BaseMenu = uimenu(app.UIFigure);
            app.BaseMenu.Text = 'Base';
            app.OpenBaseMenu = uimenu(app.BaseMenu);
            app.OpenBaseMenu.MenuSelectedFcn = createCallbackFcn(app, @OpenBaseMenuSelected, true);
            app.OpenBaseMenu.Text = 'Open Base';
            app.SaveBaseMenu = uimenu(app.BaseMenu);
            app.SaveBaseMenu.MenuSelectedFcn = createCallbackFcn(app, @SaveBaseMenuSelected, true);
            app.SaveBaseMenu.Text = 'Save Base';
            app.SaveBaseasMenu = uimenu(app.BaseMenu);
            app.SaveBaseasMenu.MenuSelectedFcn = createCallbackFcn(app, @SaveBaseasMenuSelected, true);
            app.SaveBaseasMenu.Text = 'Save Base as';
            app.ProjectMenu = uimenu(app.UIFigure);
            app.ProjectMenu.Text = 'Project';
            app.NewProjectMenu = uimenu(app.ProjectMenu);
            app.NewProjectMenu.Text = 'New Project';
            app.OpenProjectMenu = uimenu(app.ProjectMenu);
            app.OpenProjectMenu.MenuSelectedFcn = createCallbackFcn(app, @OpenProjectMenuSelected, true);
            app.OpenProjectMenu.Text = 'Open Project';
            app.SaveProjectMenu = uimenu(app.ProjectMenu);
            app.SaveProjectMenu.MenuSelectedFcn = createCallbackFcn(app, @SaveProjectMenuSelected, true);
            app.SaveProjectMenu.Text = 'Save Project';
            app.SaveProjectasMenu = uimenu(app.ProjectMenu);
            app.SaveProjectasMenu.MenuSelectedFcn = createCallbackFcn(app, @SaveProjectasMenuSelected, true);
            app.SaveProjectasMenu.Text = 'Save Project as';
            app.InfosMenu = uimenu(app.UIFigure);
            app.InfosMenu.Text = 'Infos';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.ImportButton = uibutton(app.GridLayout, 'push');
            app.ImportButton.ButtonPushedFcn = createCallbackFcn(app, @ImportButtonPushed, true);
            app.ImportButton.Text = 'Show Session in Import Module';
            app.AddSessionButton = uibutton(app.GridLayout, 'push');
            app.AddSessionButton.ButtonPushedFcn = createCallbackFcn(app, @AddSessionButtonPushed, true);
            app.AddSessionButton.Text = 'Add Session';
            app.SessionTable = uitable(app.GridLayout);
            app.SessionTable.ColumnName = '';
            app.SessionTable.RowName = {};
            app.SessionTable.ColumnEditable = [false true true true true];
            app.SessionTable.CellEditCallback = createCallbackFcn(app, @SessionTableCellEdit, true);
            app.SessionTable.SelectionChangedFcn = createCallbackFcn(app, @SessionTableSelectionChanged, true);
            app.SessionTable.Multiselect = 'off';
            app.DeleteSessionButton = uibutton(app.GridLayout, 'push');
            app.DeleteSessionButton.ButtonPushedFcn = createCallbackFcn(app, @DeleteSessionButtonPushed, true);
            app.DeleteSessionButton.Text = 'Delete Session';
            app.IntervalButton = uibutton(app.GridLayout, 'push');
            app.IntervalButton.ButtonPushedFcn = createCallbackFcn(app, @ImportButtonPushed, true);
            app.IntervalButton.Text = 'Show Session in Interval Module';
            app.ProjectTable = uitable(app.GridLayout);
            app.ProjectTable.ColumnName = '';
            app.ProjectTable.RowName = {};
            app.ProjectTable.ColumnEditable = [false true];
            app.ProjectTable.CellEditCallback = createCallbackFcn(app, @ProjectTableCellEdit, true);
            app.ProjectTable.SelectionChangedFcn = createCallbackFcn(app, @ProjectTableSelectionChanged, true);
            app.ProjectTable.Multiselect = 'off';
            app.AddProjectButton = uibutton(app.GridLayout, 'push');
            app.AddProjectButton.ButtonPushedFcn = createCallbackFcn(app, @AddProjectButtonPushed, true);
            app.AddProjectButton.Text = 'Add Project';
            app.DeleteProjectButton = uibutton(app.GridLayout, 'push');
            app.DeleteProjectButton.ButtonPushedFcn = createCallbackFcn(app, @DeleteProjectButtonPushed, true);
            app.DeleteProjectButton.Text = 'Delete Project';
            app.ClassificationButton = uibutton(app.GridLayout, 'push');
            app.ClassificationButton.ButtonPushedFcn = createCallbackFcn(app, @ImportButtonPushed, true);
            app.ClassificationButton.Text = 'Show Session in Classification Module';
            app.DriftButton = uibutton(app.GridLayout, 'push');
            app.DriftButton.ButtonPushedFcn = createCallbackFcn(app, @ImportButtonPushed, true);
            app.DriftButton.Text = 'Show Session in Drift Module';
            app.QuantificationButton = uibutton(app.GridLayout, 'push');
            app.QuantificationButton.ButtonPushedFcn = createCallbackFcn(app, @ImportButtonPushed, true);
            app.QuantificationButton.Text = 'Show Session in Quantification Module';
            app.ResultsButton = uibutton(app.GridLayout, 'push');
            app.ResultsButton.ButtonPushedFcn = createCallbackFcn(app, @ImportButtonPushed, true);
            app.ResultsButton.Text = 'Show Session in Results Module';
            app.WorkingDirectoryEditFieldLabel = uilabel(app.GridLayout);
            app.WorkingDirectoryEditFieldLabel.Text = 'Working Directory';
            app.WorkingDirectoryEditField = uieditfield(app.GridLayout, 'text');
            app.WorkingDirectoryEditField.ValueChangedFcn = createCallbackFcn(app, @WorkingDirectoryEditFieldValueChanged, true);
            app.SelectButton = uibutton(app.GridLayout, 'push');
            app.SelectButton.ButtonPushedFcn = createCallbackFcn(app, @SelectButtonPushed, true);
            app.SelectButton.Text = 'Select';
            app.DuplicateSessionButton = uibutton(app.GridLayout, 'push');
            app.DuplicateSessionButton.ButtonPushedFcn = createCallbackFcn(app, @DuplicateSessionButtonPushed, true);
            app.DuplicateSessionButton.Text = 'Duplicate Session';
            app.DuplicateProjectButton = uibutton(app.GridLayout, 'push');
            app.DuplicateProjectButton.ButtonPushedFcn = createCallbackFcn(app, @DuplicateProjectButtonPushed, true);
            app.DuplicateProjectButton.Text = 'Duplicate Project';
            app.SendProjecttoPlotModuleButton = uibutton(app.GridLayout, 'push');
            app.SendProjecttoPlotModuleButton.ButtonPushedFcn = createCallbackFcn(app, @SendProjecttoPlotModuleButtonPushed, true);
            app.SendProjecttoPlotModuleButton.Enable = 'off';
            app.SendProjecttoPlotModuleButton.Text = 'Send Project to Plot Module';
            app.SendBasetoPlotModuleButton = uibutton(app.GridLayout, 'push');
            app.SendBasetoPlotModuleButton.ButtonPushedFcn = createCallbackFcn(app, @SendBasetoPlotModuleButtonPushed, true);
            app.SendBasetoPlotModuleButton.Text = 'Send Base to Plot Module';
            app.SendSessiontoPlotModuleButton = uibutton(app.GridLayout, 'push');
            app.SendSessiontoPlotModuleButton.ButtonPushedFcn = createCallbackFcn(app, @SendSessiontoPlotModuleButtonPushed, true);
            app.SendSessiontoPlotModuleButton.Enable = 'off';
            app.SendSessiontoPlotModuleButton.Text = 'Send Session to Plot Module';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ICP_Base

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end